classdef imageProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        LoadButton              matlab.ui.control.Button
        Wavelength1SliderLabel  matlab.ui.control.Label
        Wavelength1Slider       matlab.ui.control.Slider
        Wavelength2SliderLabel  matlab.ui.control.Label
        Wavelength2Slider       matlab.ui.control.Slider
        Wavelength3SliderLabel  matlab.ui.control.Label
        Wavelength3Slider       matlab.ui.control.Slider
    end

    
    properties (Access = private)
        sample_norm;
    end
    
    methods (Access = public)
                
        function loadData(app,pathName)
            [HS,Dref,Wref,wavelength] = HS_SpecImport(pathName);
            wr_av = mean(Dref(:,:,:)); % calc mean dark reference reflectance
            dr_av = mean(Wref); % calc mean white reference reflectance
            
            % apply normalization to raw data using white and dark reference values
            % each 'sensor' (pixel, or row of pixels) is normalized using the white and
            % dark reflectance values for that sensor.
            % Illumination across the white reference bar is not even, so raw data will
            % vary in reflectance across the 'width' of the image. Compare raw and
            % normalized reflectance images and you'll see what I mean.
            tic
            for i = 1:size(HS,1)
                for j = 1:640
                    app.sample_norm(i,j,:) = (HS(i,j,:) - dr_av(1,j,:)) ./ (wr_av(1,j,:) - dr_av(1,j,:));
                end
            end
            toc % 117s
            
            % plot the normalized data
            % this example code is plotting a subset of x,y coordinates and the 200th
            % wavelength from the hypercube (x,y,z).
            updateImage(app);                     
        end
        
        
        function updateImage(app)
            imagesc(app.UIAxes,app.sample_norm(1:258,1:640,[int16(app.Wavelength1Slider.Value) int16(app.Wavelength2Slider.Value) int16(app.Wavelength3Slider.Value)]));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Display uigetdir dialog
            captureDir = uigetdir();
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(captureDir))
               loadData(app, strcat(captureDir,'\'));
            end
        end

        % Value changed function: Wavelength1Slider
        function Wavelength1SliderValueChanged(app, event)
            updateImage(app);            
        end

        % Value changed function: Wavelength2Slider
        function Wavelength2SliderValueChanged(app, event)
            updateImage(app);            
        end

        % Value changed function: Wavelength3Slider
        function Wavelength3SliderValueChanged(app, event)
            updateImage(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = {'Load'; ''};
            app.Wavelength1SliderLabel = uilabel(app.UIFigure);
            app.Wavelength1SliderLabel.Text = {'Wavelength 1'; ''};
            app.Wavelength1Slider = uislider(app.UIFigure);
            app.Wavelength1Slider.Limits = [1 224];
            app.Wavelength1Slider.ValueChangedFcn = createCallbackFcn(app, @Wavelength1SliderValueChanged, true);
            app.Wavelength1Slider.Value = 1;
            app.Wavelength2SliderLabel = uilabel(app.UIFigure);
            app.Wavelength2SliderLabel.Text = {'Wavelength 2'; ''};
            app.Wavelength2Slider = uislider(app.UIFigure);
            app.Wavelength2Slider.Limits = [1 224];
            app.Wavelength2Slider.ValueChangedFcn = createCallbackFcn(app, @Wavelength2SliderValueChanged, true);
            app.Wavelength2Slider.Value = 1;
            app.Wavelength3SliderLabel = uilabel(app.UIFigure);
            app.Wavelength3SliderLabel.Text = 'Wavelength 3';
            app.Wavelength3Slider = uislider(app.UIFigure);
            app.Wavelength3Slider.Limits = [1 224];
            app.Wavelength3Slider.ValueChangedFcn = createCallbackFcn(app, @Wavelength3SliderValueChanged, true);
            app.Wavelength3Slider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end